classdef SNA_v1_0_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        INPUTSTab                       matlab.ui.container.Tab
        RUNANALYSISButton               matlab.ui.control.Button
        SAVEButton                      matlab.ui.control.Button
        INPUTDATATHROUGHIMAGEOptionalLabel  matlab.ui.control.Label
        INPUTDATAMANUALLYLabel          matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        UploadButton                    matlab.ui.control.Button
        NoFilesSelectedLabel            matlab.ui.control.Label
        FileChosenLabel                 matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        Panel_2                         matlab.ui.container.Panel
        outputofsoilNameLabel           matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        SoilNameLabel                   matlab.ui.control.Label
        DateDatePicker                  matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        SoilColorColorPicker            matlab.ui.control.ColorPicker
        SoilColorColorPickerLabel       matlab.ui.control.Label
        ParticleSizeSlider              matlab.ui.control.Slider
        ParticleSizeSliderLabel         matlab.ui.control.Label
        TextureDropDown                 matlab.ui.control.DropDown
        TextureDropDownLabel            matlab.ui.control.Label
        CURRENTHEALTHTab                matlab.ui.container.Tab
        ADDITIONALPHYSICALINDICATORSLabel  matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        WaterRetentionGauge             matlab.ui.control.LinearGauge
        WaterRetentionGaugeLabel        matlab.ui.control.Label
        DrainageGauge                   matlab.ui.control.LinearGauge
        DrainageGaugeLabel              matlab.ui.control.Label
        AerationGauge                   matlab.ui.control.LinearGauge
        AerationGaugeLabel              matlab.ui.control.Label
        BACKButton                      matlab.ui.control.Button
        VIEWRECOMMENDATIONSButton       matlab.ui.control.Button
        SOILMICRONUTRIENTLEVELLabel     matlab.ui.control.Label
        SOILMACRONUTRIENTLEVELNPKLabel  matlab.ui.control.Label
        SOILPHLEVELLabel                matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        CalciumGauge                    matlab.ui.control.LinearGauge
        CalciumGaugeLabel               matlab.ui.control.Label
        IronGauge                       matlab.ui.control.LinearGauge
        IronGaugeLabel                  matlab.ui.control.Label
        ZincGauge                       matlab.ui.control.LinearGauge
        ZincGaugeLabel                  matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        HighAlkalinityLabel             matlab.ui.control.Label
        NeutralLabel                    matlab.ui.control.Label
        HighAcidityLabel                matlab.ui.control.Label
        Gauge                           matlab.ui.control.LinearGauge
        Panel_5                         matlab.ui.container.Panel
        PotassiumKGauge                 matlab.ui.control.LinearGauge
        PotassiumKGaugeLabel            matlab.ui.control.Label
        PhosphorusPGauge                matlab.ui.control.LinearGauge
        PhosphorusPGaugeLabel           matlab.ui.control.Label
        NitrogenNGauge                  matlab.ui.control.LinearGauge
        NitrogenNGaugeLabel             matlab.ui.control.Label
        RECOMMENDATIONSTab              matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        BACKButton_2                    matlab.ui.control.Button
        PRINTButton                     matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            % Open file selection dialog to choose an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            
            % Check if the user selected a file
            if isequal(file, 0)
                disp('User canceled image upload.');
            else
                % Read the image and display it on the axes
                img = imread(fullfile(path, file));
                imshow(img, 'Parent', app.UIAxes2);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.INPUTSTab = uitab(app.TabGroup);
            app.INPUTSTab.Title = 'INPUTS';
            app.Panel_2 = uipanel(app.INPUTSTab);
            app.TextureDropDownLabel = uilabel(app.Panel_2);
            app.TextureDropDownLabel.Text = 'Texture';
            app.TextureDropDown = uidropdown(app.Panel_2);
            app.TextureDropDown.Items = {'Smooth', 'Loose', 'Gritty', 'Silky', 'Sticky', 'Hard', 'Balanced', 'Crumbly', 'Spongy', 'Dark', 'Coarse', 'Powdery'};
            app.TextureDropDown.Value = 'Smooth';
            app.ParticleSizeSliderLabel = uilabel(app.Panel_2);
            app.ParticleSizeSliderLabel.Text = 'Particle Size';
            app.ParticleSizeSlider = uislider(app.Panel_2);
            app.SoilColorColorPickerLabel = uilabel(app.Panel_2);
            app.SoilColorColorPickerLabel.Text = 'Soil Color';
            app.SoilColorColorPicker = uicolorpicker(app.Panel_2);
            app.SoilColorColorPicker.Value = [0.490196078431373 0.368627450980392 0.368627450980392];
            app.DateDatePickerLabel = uilabel(app.Panel_2);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.Panel_2);
            app.DateDatePicker.Value = datetime([2024 10 31]);
            app.SoilNameLabel = uilabel(app.Panel_2);
            app.SoilNameLabel.Text = 'Soil Name:';
            app.Image_2 = uiimage(app.Panel_2);
            app.outputofsoilNameLabel = uilabel(app.Panel_2);
            app.outputofsoilNameLabel.Text = '(output of soilName)';
            app.Panel_3 = uipanel(app.INPUTSTab);
            app.UIAxes2 = uiaxes(app.Panel_3);
            title(app.UIAxes2, 'Image of Soil')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.FileChosenLabel = uilabel(app.Panel_3);
            app.FileChosenLabel.Text = 'File Chosen:';
            app.NoFilesSelectedLabel = uilabel(app.Panel_3);
            app.NoFilesSelectedLabel.Text = '(No Files Selected):';
            app.UploadButton = uibutton(app.Panel_3, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.INPUTDATAMANUALLYLabel = uilabel(app.INPUTSTab);
            app.INPUTDATAMANUALLYLabel.Text = 'INPUT DATA MANUALLY';
            app.INPUTDATATHROUGHIMAGEOptionalLabel = uilabel(app.INPUTSTab);
            app.INPUTDATATHROUGHIMAGEOptionalLabel.Text = 'INPUT DATA THROUGH IMAGE (Optional)';
            app.SAVEButton = uibutton(app.INPUTSTab, 'push');
            app.SAVEButton.Text = 'SAVE';
            app.RUNANALYSISButton = uibutton(app.INPUTSTab, 'push');
            app.RUNANALYSISButton.Text = 'RUN ANALYSIS';
            app.CURRENTHEALTHTab = uitab(app.TabGroup);
            app.CURRENTHEALTHTab.Title = 'CURRENT HEALTH';
            app.Panel_5 = uipanel(app.CURRENTHEALTHTab);
            app.NitrogenNGaugeLabel = uilabel(app.Panel_5);
            app.NitrogenNGaugeLabel.Text = 'Nitrogen (N)';
            app.NitrogenNGauge = uigauge(app.Panel_5, 'linear');
            app.PhosphorusPGaugeLabel = uilabel(app.Panel_5);
            app.PhosphorusPGaugeLabel.Text = 'Phosphorus (P)';
            app.PhosphorusPGauge = uigauge(app.Panel_5, 'linear');
            app.PotassiumKGaugeLabel = uilabel(app.Panel_5);
            app.PotassiumKGaugeLabel.Text = 'Potassium (K)';
            app.PotassiumKGauge = uigauge(app.Panel_5, 'linear');
            app.Panel_4 = uipanel(app.CURRENTHEALTHTab);
            app.Gauge = uigauge(app.Panel_4, 'linear');
            app.HighAcidityLabel = uilabel(app.Panel_4);
            app.HighAcidityLabel.Text = 'High Acidity';
            app.NeutralLabel = uilabel(app.Panel_4);
            app.NeutralLabel.Text = 'Neutral';
            app.HighAlkalinityLabel = uilabel(app.Panel_4);
            app.HighAlkalinityLabel.Text = 'High Alkalinity';
            app.Panel_6 = uipanel(app.CURRENTHEALTHTab);
            app.ZincGaugeLabel = uilabel(app.Panel_6);
            app.ZincGaugeLabel.Text = 'Zinc';
            app.ZincGauge = uigauge(app.Panel_6, 'linear');
            app.IronGaugeLabel = uilabel(app.Panel_6);
            app.IronGaugeLabel.Text = 'Iron';
            app.IronGauge = uigauge(app.Panel_6, 'linear');
            app.CalciumGaugeLabel = uilabel(app.Panel_6);
            app.CalciumGaugeLabel.Text = 'Calcium';
            app.CalciumGauge = uigauge(app.Panel_6, 'linear');
            app.SOILPHLEVELLabel = uilabel(app.CURRENTHEALTHTab);
            app.SOILPHLEVELLabel.Text = 'SOIL PH LEVEL';
            app.SOILMACRONUTRIENTLEVELNPKLabel = uilabel(app.CURRENTHEALTHTab);
            app.SOILMACRONUTRIENTLEVELNPKLabel.Text = 'SOIL MACRONUTRIENT LEVEL (NPK)';
            app.SOILMICRONUTRIENTLEVELLabel = uilabel(app.CURRENTHEALTHTab);
            app.SOILMICRONUTRIENTLEVELLabel.Text = 'SOIL MICRONUTRIENT LEVEL';
            app.VIEWRECOMMENDATIONSButton = uibutton(app.CURRENTHEALTHTab, 'push');
            app.VIEWRECOMMENDATIONSButton.Text = 'VIEW RECOMMENDATIONS';
            app.BACKButton = uibutton(app.CURRENTHEALTHTab, 'push');
            app.BACKButton.Text = 'BACK';
            app.Panel_7 = uipanel(app.CURRENTHEALTHTab);
            app.AerationGaugeLabel = uilabel(app.Panel_7);
            app.AerationGaugeLabel.Text = 'Aeration';
            app.AerationGauge = uigauge(app.Panel_7, 'linear');
            app.DrainageGaugeLabel = uilabel(app.Panel_7);
            app.DrainageGaugeLabel.Text = 'Drainage';
            app.DrainageGauge = uigauge(app.Panel_7, 'linear');
            app.WaterRetentionGaugeLabel = uilabel(app.Panel_7);
            app.WaterRetentionGaugeLabel.Text = 'Water Retention';
            app.WaterRetentionGauge = uigauge(app.Panel_7, 'linear');
            app.ADDITIONALPHYSICALINDICATORSLabel = uilabel(app.CURRENTHEALTHTab);
            app.ADDITIONALPHYSICALINDICATORSLabel.Text = 'ADDITIONAL PHYSICAL INDICATORS';
            app.RECOMMENDATIONSTab = uitab(app.TabGroup);
            app.RECOMMENDATIONSTab.Title = 'RECOMMENDATIONS';
            app.UIAxes = uiaxes(app.RECOMMENDATIONSTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RECOMMENDATIONSTab);
            app.UITable.ColumnName = {'Crop Type'; 'Fertilizer'; 'pH'; 'Season'};
            app.UITable.RowName = {};
            app.PRINTButton = uibutton(app.RECOMMENDATIONSTab, 'push');
            app.PRINTButton.Text = 'PRINT';
            app.BACKButton_2 = uibutton(app.RECOMMENDATIONSTab, 'push');
            app.BACKButton_2.Text = 'BACK';
            app.Label = uilabel(app.RECOMMENDATIONSTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNA_v1_0_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end