classdef BasicCalc2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        CalculatorPanel  matlab.ui.container.Panel
        multiply         matlab.ui.control.Button
        divide           matlab.ui.control.Button
        subtract         matlab.ui.control.Button
        add              matlab.ui.control.Button
        nine             matlab.ui.control.Button
        equals           matlab.ui.control.Button
        six              matlab.ui.control.Button
        three            matlab.ui.control.Button
        del              matlab.ui.control.Button
        eight            matlab.ui.control.Button
        five             matlab.ui.control.Button
        two              matlab.ui.control.Button
        zero             matlab.ui.control.Button
        seven            matlab.ui.control.Button
        four             matlab.ui.control.Button
        one              matlab.ui.control.Button
        textBox          matlab.ui.control.EditField
        clear            matlab.ui.control.Button
    end

    
    properties (Access = private)
        operatorClickedFlag = false; % To track when an operator is clicked
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: clear
        function clearButtonPushed(app, event)
            app.textBox.Value = '';
            app.textBox.UserData = true;
        end

        % Button pushed function: del
        function delButtonPushed(app, event)
            currentText = app.textBox.Value;
            if app.operatorClickedFlag
                app.textBox.Value = currentText(1:end-3);
                app.operatorClickedFlag = false;
            else
                app.textBox.Value = currentText(1:end-1);
            end
        end

        % Button pushed function: zero
        function zeroButtonPushed(app, event)
            if app.textBox.UserData     % To decide whether to append the value or not
                app.textBox.Value = '0'; 
                app.textBox.UserData = false;  
            else
                app.textBox.Value = [app.textBox.Value '0'];
            end
        end

        % Button pushed function: one
        function oneButtonPushed(app, event)
            if app.textBox.UserData
                app.textBox.Value = '1';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '1'];
            end
        end

        % Button pushed function: two
        function twoButtonPushed(app, event)
            if app.textBox.UserData
                app.textBox.Value = '2';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '2'];
            end
        end

        % Button pushed function: three
        function threeButtonPushed(app, event)
            if app.textBox.UserData
                app.textBox.Value = '3';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '3'];
            end
        end

        % Button pushed function: four
        function fourButtonPushed(app, event)
            if app.textBox.UserData
                app.textBox.Value = '4';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '4'];
            end
        end

        % Button pushed function: five
        function fiveButtonPushed(app, event)
            if app.textBox.UserData
                app.textBox.Value = '5';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '5'];
            end
        end

        % Button pushed function: six
        function sixButtonPushed(app, event)
            if app.textBox.UserData
                app.textBox.Value = '6';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '6'];
            end
        end

        % Button pushed function: seven
        function sevenButtonPushed(app, event)
            if app.textBox.UserData
                app.textBox.Value = '7';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '7'];
            end
        end

        % Button pushed function: eight
        function eightButtonPushed(app, event)
            if app.textBox.UserData
                app.textBox.Value = '8';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '8'];
            end
        end

        % Button pushed function: nine
        function nineButtonPushed(app, event)
            if app.textBox.UserData
                app.textBox.Value = '9';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '9'];
            end
        end

        % Button pushed function: add
        function addButtonPushed(app, event)
            app.textBox.Value = [app.textBox.Value ' + '];
            app.operatorClickedFlag = true;
        end

        % Button pushed function: subtract
        function subtractButtonPushed(app, event)
            app.textBox.Value = [app.textBox.Value ' - '];
            app.operatorClickedFlag = true;
        end

        % Button pushed function: multiply
        function multiplyButtonPushed(app, event)
            app.textBox.Value = [app.textBox.Value ' * '];
            app.operatorClickedFlag = true;
        end

        % Button pushed function: divide
        function divideButtonPushed(app, event)
            app.textBox.Value = [app.textBox.Value ' / '];
            app.operatorClickedFlag = true;
        end

        % Button pushed function: equals
        function equalsButtonPushed(app, event)
            result = eval(app.textBox.Value);
            app.textBox.Value = num2str(result);
            app.textBox.UserData = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculatorPanel = uipanel(app.UIFigure);
            app.CalculatorPanel.Title = 'Calculator';
            app.clear = uibutton(app.CalculatorPanel, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clear.Text = 'C';
            app.textBox = uieditfield(app.CalculatorPanel, 'text');
            app.one = uibutton(app.CalculatorPanel, 'push');
            app.one.ButtonPushedFcn = createCallbackFcn(app, @oneButtonPushed, true);
            app.one.Text = '1';
            app.four = uibutton(app.CalculatorPanel, 'push');
            app.four.ButtonPushedFcn = createCallbackFcn(app, @fourButtonPushed, true);
            app.four.Text = '4';
            app.seven = uibutton(app.CalculatorPanel, 'push');
            app.seven.ButtonPushedFcn = createCallbackFcn(app, @sevenButtonPushed, true);
            app.seven.Text = '7';
            app.zero = uibutton(app.CalculatorPanel, 'push');
            app.zero.ButtonPushedFcn = createCallbackFcn(app, @zeroButtonPushed, true);
            app.zero.Text = '0';
            app.two = uibutton(app.CalculatorPanel, 'push');
            app.two.ButtonPushedFcn = createCallbackFcn(app, @twoButtonPushed, true);
            app.two.Text = '2';
            app.five = uibutton(app.CalculatorPanel, 'push');
            app.five.ButtonPushedFcn = createCallbackFcn(app, @fiveButtonPushed, true);
            app.five.Text = '5';
            app.eight = uibutton(app.CalculatorPanel, 'push');
            app.eight.ButtonPushedFcn = createCallbackFcn(app, @eightButtonPushed, true);
            app.eight.Text = '8';
            app.del = uibutton(app.CalculatorPanel, 'push');
            app.del.ButtonPushedFcn = createCallbackFcn(app, @delButtonPushed, true);
            app.del.Text = 'Del';
            app.three = uibutton(app.CalculatorPanel, 'push');
            app.three.ButtonPushedFcn = createCallbackFcn(app, @threeButtonPushed, true);
            app.three.Text = '3';
            app.six = uibutton(app.CalculatorPanel, 'push');
            app.six.ButtonPushedFcn = createCallbackFcn(app, @sixButtonPushed, true);
            app.six.Text = '6';
            app.equals = uibutton(app.CalculatorPanel, 'push');
            app.equals.ButtonPushedFcn = createCallbackFcn(app, @equalsButtonPushed, true);
            app.equals.Text = '=';
            app.nine = uibutton(app.CalculatorPanel, 'push');
            app.nine.ButtonPushedFcn = createCallbackFcn(app, @nineButtonPushed, true);
            app.nine.Text = '9';
            app.add = uibutton(app.CalculatorPanel, 'push');
            app.add.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.add.Text = '+';
            app.subtract = uibutton(app.CalculatorPanel, 'push');
            app.subtract.ButtonPushedFcn = createCallbackFcn(app, @subtractButtonPushed, true);
            app.subtract.Text = '-';
            app.divide = uibutton(app.CalculatorPanel, 'push');
            app.divide.ButtonPushedFcn = createCallbackFcn(app, @divideButtonPushed, true);
            app.divide.Text = '÷';
            app.multiply = uibutton(app.CalculatorPanel, 'push');
            app.multiply.ButtonPushedFcn = createCallbackFcn(app, @multiplyButtonPushed, true);
            app.multiply.Text = '×';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BasicCalc2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end