classdef BasicCalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        CalculatorPanel  matlab.ui.container.Panel
        six_3            matlab.ui.control.Button
        nine_2           matlab.ui.control.Button
        eight_2          matlab.ui.control.Button
        seven_2          matlab.ui.control.Button
        five_2           matlab.ui.control.Button
        four_2           matlab.ui.control.Button
        three_2          matlab.ui.control.Button
        two_2            matlab.ui.control.Button
        one_2            matlab.ui.control.Button
        zero_2           matlab.ui.control.Button
        del              matlab.ui.control.Button
        clear            matlab.ui.control.Button
        textBox          matlab.ui.control.EditField
        equals           matlab.ui.control.Button
        divide           matlab.ui.control.Button
        multiply         matlab.ui.control.Button
        subtract         matlab.ui.control.Button
        add              matlab.ui.control.Button
    end

    
    properties (Access = private)
        operatorClickedFlag = false % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function zeroButtonPushed(app, event)

        end

        % Value changing function: textBox
        function textBoxValueChanging(app, event)

        end

        % Value changed function: textBox
        function textBoxValueChanged(app, event)

        end

        % Callback function
        function sevenButtonPushed(app, event)
         
        end

        % Callback function
        function eightButtonPushed(app, event)
            if str2double(app.textBox.Value) >= 0
                app.textBox.Value = '8';
            else
                app.textBox.Value = [app.textBox.Value '8'];
            end
        end

        % Callback function
        function nineButtonPushed(app, event)

        end

        % Callback function
        function fourButtonPushed(app, event)
 
        end

        % Callback function
        function fiveButtonPushed(app, event)

        end

        % Callback function
        function sixButtonPushed(app, event)

        end

        % Callback function
        function twoButtonPushed(app, event)

        end

        % Callback function
        function threeButtonPushed(app, event)

        end

        % Callback function
        function decimal_pointButtonPushed(app, event)
          
        end

        % Button pushed function: add
        function addButtonPushed(app, event)
            app.textBox.Value = [app.textBox.Value ' + '];
            app.operatorClickedFlag = true;
        end

        % Button pushed function: subtract
        function subtractButtonPushed(app, event)
            app.textBox.Value = [app.textBox.Value ' - '];
            app.operatorClickedFlag = true;
        end

        % Button pushed function: multiply
        function multiplyButtonPushed(app, event)
            app.textBox.Value = [app.textBox.Value ' * '];
            app.operatorClickedFlag = true;
        end

        % Button pushed function: divide
        function divideButtonPushed(app, event)
            app.textBox.Value = [app.textBox.Value ' / '];
            app.operatorClickedFlag = true;
        end

        % Button pushed function: equals
        function equalsButtonPushed(app, event)
            result = eval(app.textBox.Value);
            app.textBox.Value = num2str(result);
            app.textBox.UserData = true;
        end

        % Button pushed function: clear
        function clearButtonPushed(app, event)
            app.textBox.Value = '';
            app.textBox.UserData = true;
        end

        % Button pushed function: del
        function delButtonPushed(app, event)
            currentText = app.textBox.Value;
            if app.operatorClickedFlag
                app.textBox.Value = currentText(1:end-3);
                app.operatorClickedFlag = false;
            else
                app.textBox.Value = currentText(1:end-1);
            end
        end

        % Button pushed function: zero_2
        function zero_2ButtonPushed(app, event)
            if app.textBox.UserData
                app.textBox.Value = '0';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '0'];
            end
        end

        % Button pushed function: one_2
        function one_2ButtonPushed(app, event)
            if app.textBox.UserData
                app.textBox.Value = '1';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '1'];
            end
        end

        % Button pushed function: two_2
        function two_2ButtonPushed(app, event)
            if app.textBox.UserData
                app.textBox.Value = '2';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '2'];
            end
        end

        % Button pushed function: three_2
        function three_2ButtonPushed(app, event)
             if app.textBox.UserData
                app.textBox.Value = '3';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '3'];
            end
        end

        % Button pushed function: four_2
        function four_2ButtonPushed(app, event)
             if app.textBox.UserData
                app.textBox.Value = '4';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '4'];
            end
        end

        % Button pushed function: five_2
        function five_2ButtonPushed(app, event)
             if app.textBox.UserData
                app.textBox.Value = '5';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '5'];
            end
        end

        % Callback function
        function six_2ButtonPushed(app, event)
          
        end

        % Button pushed function: seven_2
        function seven_2ButtonPushed(app, event)
             if app.textBox.UserData
                app.textBox.Value = '7';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '7'];
            end
        end

        % Button pushed function: eight_2
        function eight_2ButtonPushed(app, event)
            if app.textBox.UserData
                app.textBox.Value = '8';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '8'];
            end
        end

        % Button pushed function: nine_2
        function nine_2ButtonPushed(app, event)
             if app.textBox.UserData
                app.textBox.Value = '9';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '9'];
            end
        end

        % Button pushed function: six_3
        function six_3ButtonPushed(app, event)
              if app.textBox.UserData
                app.textBox.Value = '6';
                app.textBox.UserData = false;
            else
                app.textBox.Value = [app.textBox.Value '6'];
              end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculatorPanel = uipanel(app.UIFigure);
            app.CalculatorPanel.Title = 'Calculator';
            app.add = uibutton(app.CalculatorPanel, 'push');
            app.add.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.add.Text = '+';
            app.subtract = uibutton(app.CalculatorPanel, 'push');
            app.subtract.ButtonPushedFcn = createCallbackFcn(app, @subtractButtonPushed, true);
            app.subtract.Text = '-';
            app.multiply = uibutton(app.CalculatorPanel, 'push');
            app.multiply.ButtonPushedFcn = createCallbackFcn(app, @multiplyButtonPushed, true);
            app.multiply.Text = '×';
            app.divide = uibutton(app.CalculatorPanel, 'push');
            app.divide.ButtonPushedFcn = createCallbackFcn(app, @divideButtonPushed, true);
            app.divide.Text = '÷';
            app.equals = uibutton(app.CalculatorPanel, 'push');
            app.equals.ButtonPushedFcn = createCallbackFcn(app, @equalsButtonPushed, true);
            app.equals.Text = '=';
            app.textBox = uieditfield(app.CalculatorPanel, 'text');
            app.textBox.ValueChangedFcn = createCallbackFcn(app, @textBoxValueChanged, true);
            app.textBox.ValueChangingFcn = createCallbackFcn(app, @textBoxValueChanging, true);
            app.clear = uibutton(app.CalculatorPanel, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clear.Text = 'C';
            app.del = uibutton(app.CalculatorPanel, 'push');
            app.del.ButtonPushedFcn = createCallbackFcn(app, @delButtonPushed, true);
            app.del.Text = 'Del';
            app.zero_2 = uibutton(app.CalculatorPanel, 'push');
            app.zero_2.ButtonPushedFcn = createCallbackFcn(app, @zero_2ButtonPushed, true);
            app.zero_2.Text = '0';
            app.one_2 = uibutton(app.CalculatorPanel, 'push');
            app.one_2.ButtonPushedFcn = createCallbackFcn(app, @one_2ButtonPushed, true);
            app.one_2.Text = '1';
            app.two_2 = uibutton(app.CalculatorPanel, 'push');
            app.two_2.ButtonPushedFcn = createCallbackFcn(app, @two_2ButtonPushed, true);
            app.two_2.Text = '2';
            app.three_2 = uibutton(app.CalculatorPanel, 'push');
            app.three_2.ButtonPushedFcn = createCallbackFcn(app, @three_2ButtonPushed, true);
            app.three_2.Text = '3';
            app.four_2 = uibutton(app.CalculatorPanel, 'push');
            app.four_2.ButtonPushedFcn = createCallbackFcn(app, @four_2ButtonPushed, true);
            app.four_2.Text = '4';
            app.five_2 = uibutton(app.CalculatorPanel, 'push');
            app.five_2.ButtonPushedFcn = createCallbackFcn(app, @five_2ButtonPushed, true);
            app.five_2.Text = '5';
            app.seven_2 = uibutton(app.CalculatorPanel, 'push');
            app.seven_2.ButtonPushedFcn = createCallbackFcn(app, @seven_2ButtonPushed, true);
            app.seven_2.Text = '7';
            app.eight_2 = uibutton(app.CalculatorPanel, 'push');
            app.eight_2.ButtonPushedFcn = createCallbackFcn(app, @eight_2ButtonPushed, true);
            app.eight_2.Text = '8';
            app.nine_2 = uibutton(app.CalculatorPanel, 'push');
            app.nine_2.ButtonPushedFcn = createCallbackFcn(app, @nine_2ButtonPushed, true);
            app.nine_2.Text = '9';
            app.six_3 = uibutton(app.CalculatorPanel, 'push');
            app.six_3.ButtonPushedFcn = createCallbackFcn(app, @six_3ButtonPushed, true);
            app.six_3.Text = '6';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BasicCalc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end