classdef simpleCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TextArea                 matlab.ui.control.TextArea
        clearallButton           matlab.ui.control.Button
        clearoneButton           matlab.ui.control.Button
        EnterCalcEditField       matlab.ui.control.EditField
        EnterCalcEditFieldLabel  matlab.ui.control.Label
        ResultEditField          matlab.ui.control.NumericEditField
        ResultEditFieldLabel     matlab.ui.control.Label
        calculateButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
            %{
                if the text field is empty return zero
                Implemet validation later
            %}
            query = app.EnterCalcEditField.Value;

            % Validate input
            try
                % Calculate result
                result = eval(query);
                app.ResultEditField.Value = result;
            catch
                app.ResultEditField.Value = 0;
            end
        end

        % Button pushed function: clearallButton
        function clearallButtonPushed(app, event)
            app.EnterCalcEditField.Value = "";
        end

        % Button pushed function: clearoneButton
        function clearoneButtonPushed(app, event)
            query = app.EnterCalcEditField.Value;
            len = length(query);
            if len > 0
                if len < 2
                    app.EnterCalcEditField.Value = "";
                else
                    app.EnterCalcEditField.Value = query(1:end-1);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.calculateButton = uibutton(app.UIFigure, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResultEditField.Placeholder = '0';
            app.EnterCalcEditFieldLabel = uilabel(app.UIFigure);
            app.EnterCalcEditFieldLabel.Text = 'Enter Calc';
            app.EnterCalcEditField = uieditfield(app.UIFigure, 'text');
            app.EnterCalcEditField.CharacterLimits = [0 50];
            app.EnterCalcEditField.Placeholder = '(5*5+2-1)/2';
            app.clearoneButton = uibutton(app.UIFigure, 'push');
            app.clearoneButton.ButtonPushedFcn = createCallbackFcn(app, @clearoneButtonPushed, true);
            app.clearoneButton.Text = 'clear one';
            app.clearallButton = uibutton(app.UIFigure, 'push');
            app.clearallButton.ButtonPushedFcn = createCallbackFcn(app, @clearallButtonPushed, true);
            app.clearallButton.Text = 'clear all';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'This is a simplistic calculator that processes input from the keybpard. you don''t need to enclose your input in strings, just type the expression straight into the editor. Note: input must be a valid mathematical expression else the result will remain as zero'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simpleCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end